classdef Complete_TS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FilenameEditFieldLabel        matlab.ui.control.Label
        UITable2                      matlab.ui.control.Table
        TimeresolutionEditFieldLabel  matlab.ui.control.Label
        ProcessButton                 matlab.ui.control.Button
        TimeresolutionEditField       matlab.ui.control.NumericEditField
        UITable                       matlab.ui.control.Table
        LoadButton                    matlab.ui.control.Button
        FilenameEditField             matlab.ui.control.EditField
        RawLabel                      matlab.ui.control.Label
        ProcessedLabel                matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        OutputButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        t % Description
        t2       
        date
        data
        date_mu % Description
        data_mu % Description        
    end
    
    methods (Access = private)
        
        function [data_min,date_min]=makeup_time_simple(app,data,date,resolution)

            minDate = min(date); 
            maxDate = max(date);
            minDate.Second=0;
            maxDate.Second=0;
            minDate.Minute=0;
            maxDate.Minute=0;
            maxDate.Hour=maxDate.Hour+1;
            Con_date=minDate:minutes(resolution):maxDate;

            Con_date=Con_date';

            for i=1:length(Con_date)-1
    
    
             data_min(i,:)=nanmean(data(date>=Con_date(i) & date<Con_date(i+1),:),1);


           end


             date_min=Con_date(1:end-1);
             
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.xlsx');
            if isnumeric(filename) % user clicked Cancel
                return
            end
            app.FilenameEditField.Value=[pathname,filename];
            % sheetNames=app.SheetsDropDown.Value;
    
            app.t=readtable(fullfile(pathname,filename),"Sheet",1);
            app.data=table2array(app.t(:,2:end));
            app.date=table2array(app.t(:,1));
            % app.dataTable = t;
            app.UITable.Data=app.t; 
            app.UITable.ColumnName = app.t.Properties.VariableNames;

            figure(app.UIFigure);
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                             'Message','Processing');
            [app.data_mu,app.date_mu]=makeup_time_simple(app,app.data,app.date,app.TimeresolutionEditField.Value);
            app.t2=timetable(app.date_mu,app.data_mu);
            app.t2=timetable2table(app.t2);
            % app.date_mu=datetime(app.t2(:,1),'ConvertFrom','datenum');
            app.UITable2.Data=app.t2; 
            % app.UITable2.ColumnName = app.t2.Properties.VariableNames;
            figure(app.UIFigure);
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
            [filename, path] = uiputfile('*.xlsx', 'Save Data as Excel');
            if isequal(filename, 0) || isequal(path, 0)
                % User canceled the dialog
                return;
            end
            
            % Construct the full file path
            filepath = fullfile(path, filename);
            
            % Write data to Excel file
            writetable(app.t2, filepath);
            
            % Display success message
            uialert(app.UIFigure, 'Data exported to Excel successfully.', 'Success', 'Icon', 'success');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.TimeresolutionEditFieldLabel = uilabel(app.UIFigure);
            app.TimeresolutionEditFieldLabel.Text = 'Time resolution';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.TimeresolutionEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeresolutionEditField.Value = 1;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.RawLabel = uilabel(app.UIFigure);
            app.RawLabel.Text = 'Raw';
            app.ProcessedLabel = uilabel(app.UIFigure);
            app.ProcessedLabel.Text = 'Processed';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'E:\CIMS_data\tof-cims storehouse\matlab app\head.png';
            app.OutputButton = uibutton(app.UIFigure, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Text = 'Output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Complete_TS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end